classdef PD2control_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        UIAxes              matlab.ui.control.UIAxes
        Button              matlab.ui.control.Button
        KpEditFieldLabel    matlab.ui.control.Label
        KpEditField         matlab.ui.control.NumericEditField
        KdEditFieldLabel    matlab.ui.control.Label
        KdEditField         matlab.ui.control.NumericEditField
        TimeEditFieldLabel  matlab.ui.control.Label
        TimeEditField       matlab.ui.control.NumericEditField
        r0EditFieldLabel    matlab.ui.control.Label
        r0EditField         matlab.ui.control.NumericEditField
        v0EditFieldLabel    matlab.ui.control.Label
        v0EditField         matlab.ui.control.NumericEditField
        PDLabel             matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: Button
        function run(app, event)
            global Kp Kd;
            Kp=(app.KpEditField.Value);
            Kd=(app.KdEditField.Value);
            st=(app.TimeEditField.Value);
            r0=(app.r0EditField.Value);
            v0=(app.v0EditField.Value);
            [t,x] = ode45(@PD2,[0 10],[-10 1]);
            plot(app.UIAxes,t,x(:,1),'-r',t,x(:,2),'--b')
            legend (app.UIAxes,'r','v')
           
        end

        % Value changed function: TimeEditField
        function Time(app, event)
            value = app.TimeEditField.Value;
            
        end

        % Value changed function: KdEditField
        function Kd(app, event)
            value = app.KdEditField.Value;
            
        end

        % Value changed function: v0EditField
        function v0(app, event)
            value = app.v0EditField.Value;
            
        end

        % Value changed function: KpEditField
        function Kp(app, event)
            value = app.KpEditField.Value;
            
        end

        % Value changed function: r0EditField
        function r0(app, event)
            value = app.r0EditField.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time（s）')
            app.UIAxes.XTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.UIAxes.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @run, true);
            app.Button.Text = '运行';
            app.KpEditFieldLabel = uilabel(app.UIFigure);
            app.KpEditFieldLabel.Text = 'Kp';
            app.KpEditField = uieditfield(app.UIFigure, 'numeric');
            app.KpEditField.ValueChangedFcn = createCallbackFcn(app, @Kp, true);
            app.KdEditFieldLabel = uilabel(app.UIFigure);
            app.KdEditFieldLabel.Text = 'Kd';
            app.KdEditField = uieditfield(app.UIFigure, 'numeric');
            app.KdEditField.ValueChangedFcn = createCallbackFcn(app, @Kd, true);
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeEditField.ValueChangedFcn = createCallbackFcn(app, @Time, true);
            app.r0EditFieldLabel = uilabel(app.UIFigure);
            app.r0EditFieldLabel.Text = 'r0';
            app.r0EditField = uieditfield(app.UIFigure, 'numeric');
            app.r0EditField.ValueChangedFcn = createCallbackFcn(app, @r0, true);
            app.v0EditFieldLabel = uilabel(app.UIFigure);
            app.v0EditFieldLabel.Text = 'v0';
            app.v0EditField = uieditfield(app.UIFigure, 'numeric');
            app.v0EditField.ValueChangedFcn = createCallbackFcn(app, @v0, true);
            app.PDLabel = uilabel(app.UIFigure);
            app.PDLabel.Text = '双积分系统PD控制分析';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PD2control_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end